classdef surface2volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Surface2volumesettingsPanel   matlab.ui.container.Panel
        ThicknessEditField            matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel       matlab.ui.control.Label
        AnglethresholdEditField       matlab.ui.control.NumericEditField
        AnglethresholdEditFieldLabel  matlab.ui.control.Label
        RunautomationButton           matlab.ui.control.Button
        SettingsforplottingPanel      matlab.ui.container.Panel
        ShowcontrolpolygonCheckBox    matlab.ui.control.CheckBox
        PlottingresolutionEditField   matlab.ui.control.NumericEditField
        PlottingresolutionEditFieldLabel  matlab.ui.control.Label
        CamlightCheckBox              matlab.ui.control.CheckBox
        ORLabel                       matlab.ui.control.Label
        ModelDropDown                 matlab.ui.control.DropDown
        Loadg2fileButton              matlab.ui.control.Button
        Surface2volumealgorithmLabel  matlab.ui.control.Label
        ModelDropDownLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        thickness   % Thickness of patches to be extruded
        nurbs_surf  % original surface patches
        nurbs_vol   % resulting nurbs volume
        sharpAngle 
        camlightHandle
        controlPolygonHandle
        plotControlPolygon
        plotElementEdges
        plotObject
    end
    
    methods (Access = private)
               
        function h = plot_nurbs_surf(app,options)
            if nargin < 2
                color = getColor();
                res = app.PlottingresolutionEditField.Value*ones(1,3);
                options = struct('plotControlPolygon',app.plotControlPolygon,'plotParmDir',...
                    0,'color',color,'resolution',res);
            end
            ax = app.UIAxes;
            axis(ax,'off')
            h = plotNURBSvec(ax,app.nurbs_surf,options);
            axis(ax, 'equal')
            if app.CamlightCheckBox.Value && ~(~isempty(app.camlightHandle) && isvalid(app.camlightHandle) && ~strcmp(app.camlightHandle.Visible,'on'))
                app.camlightHandle = camlight(ax);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            app.thickness = app.ThicknessEditField.Value;
            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            model = app.ModelDropDown.Value;
            ax = app.UIAxes;
            switch model
                case 'Mutter'
                    nurbs_input = read_g2('../IGA-geometries/Mutter/Mutter.g2');
                    nurbs_input = cleanNURBS(nurbs_input,[],1e-2);
                    app.nurbs_surf  = extractFreeSurface(nurbs_input);
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                case 'Capablanca'
                    load('../../results/ASIGA/Capablanca/nurbs.mat','nurbs')
                    app.nurbs_surf = nurbs;
            
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/Capablanca/CAD.fig');
                case 'Capablanca_woRooks'
                    load('../../results/ASIGA/Capablanca/nurbs_woRook.mat','nurbs')
                    app.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/Capablanca/CADwoRook.fig');
                case 'BCA'
                    load('BeTSSi_BCA_p2_unRefined.mat','nurbs')
                    app.nurbs_surf = nurbs;
                    app.ThicknessEditField.Value = 1.1;
                    % sharpAngle = 100*pi/180; % Threshold for a "sharp" angle
                    app.AnglethresholdEditField.Value = 120; % Threshold for a "sharp" angle
                    % sharpAngle = 125*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 135*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 160*pi/180; % Threshold for a "sharp" angle
                    % sharpAngle = 161*pi/180; % Threshold for a "sharp" angle % In order to include connections over depth rudders
                    % sharpAngle = 173*pi/180; % Threshold for a "sharp" angle
                    a = open('../../results/ASIGA/BCA/CAD.fig');
                case 'S1_interior'
                    app.nurbs_surf = getEllipsoidData('parm',2,'S2V_algorithm',{'A1_2'});
                    app.nurbs_surf = flipNURBSparametrization(app.nurbs_surf,1);
                    app.AnglethresholdEditField.Value = 140; % Threshold for a "sharp" angle
                case 'S1'
                    app.nurbs_surf = getEllipsoidData('parm',1);
                    app.AnglethresholdEditField.Value = 140; % Threshold for a "sharp" angle
            end
            cla(ax)
            if exist('a','var')
                blah_items = get(gca,'Children');
                copyobj(blah_items,ax)
                close(a)
            end
            app.plotControlPolygon = app.ShowcontrolpolygonCheckBox.Value;
            app.plotElementEdges = app.ShowelementsCheckBox.Value;
            app.plotObject = true;
            if ~ismember(model,{'BCA','Capablanca','Capablanca_woRooks'})
                plot_nurbs_surf(app)
            end
            view(ax,getView());
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: AnglethresholdEditField
        function AnglethresholdEditFieldValueChanged(app, event)
            app.sharpAngle = app.AnglethresholdEditField.Value*pi/180;
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            app.thickness = app.ThicknessEditField.Value;
        end

        % Button pushed function: RunautomationButton
        function RunautomationButtonPushed(app, event)
            app.nurbs_vol = surfaceToVolume(app.nurbs_surf,'t',app.thickness,'sharpAngle',app.sharpAngle,'ax',app.UIAxes);
        end

        % Button pushed function: Loadg2fileButton
        function Loadg2fileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.g2', 'Open a .g2 file','NURBSgeometries/g2files/');
            if filename == -1
                warning(['Could not open file ' filename])
                return
            elseif filename == 0
                return
            end
            nurbs_input = read_g2([pathname, filename]);
            if nurbs_input{1}.d_p == 3
                app.nurbs_surf = extractFreeSurface(nurbs_input);
            elseif nurbs_input{1}.d_p == 2
                app.nurbs_surf = nurbs_input;
            else
                error('Nonsensical input')
            end
            cla(app.UIAxes)
            plot_nurbs_surf(app)
        end

        % Value changed function: CamlightCheckBox
        function CamlightCheckBoxValueChanged(app, event)
            value = app.CamlightCheckBox.Value;
            if value
                app.camlightHandle = camlight(app.UIAxes);
            else
                app.camlightHandle.Visible = 'off';
            end
        end

        % Value changed function: PlottingresolutionEditField
        function PlottingresolutionEditFieldValueChanged(app, event)
            plot_nurbs_surf(app)
        end

        % Value changed function: ShowcontrolpolygonCheckBox
        function ShowcontrolpolygonCheckBoxValueChanged(app, event)
            value = app.ShowcontrolpolygonCheckBox.Value;
            if value
                app.plotObject = false;
                app.plotElementEdges = false;
                options = struct('plotObject',false,'plotControlPolygon',app.plotControlPolygon);
                app.controlPolygonHandle = plot_nurbs_surf(app,options);
            else
                set(app.controlPolygonHandle,'Visible','off');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {473, 473};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {305, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.Surface2volumealgorithmLabel = uilabel(app.LeftPanel);
            app.Surface2volumealgorithmLabel.Text = 'Surface2volume algorithm';
            app.Loadg2fileButton = uibutton(app.LeftPanel, 'push');
            app.Loadg2fileButton.ButtonPushedFcn = createCallbackFcn(app, @Loadg2fileButtonPushed, true);
            app.Loadg2fileButton.Text = 'Load g2-file';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<Select model>', 'BCA', 'S1', 'S1_interior'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<Select model>';
            app.ORLabel = uilabel(app.LeftPanel);
            app.ORLabel.Text = 'OR';
            app.SettingsforplottingPanel = uipanel(app.LeftPanel);
            app.SettingsforplottingPanel.Title = 'Settings for plotting';
            app.CamlightCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.CamlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamlightCheckBoxValueChanged, true);
            app.CamlightCheckBox.Text = 'Camlight';
            app.CamlightCheckBox.Value = true;
            app.PlottingresolutionEditFieldLabel = uilabel(app.SettingsforplottingPanel);
            app.PlottingresolutionEditFieldLabel.Text = 'Plotting resolution';
            app.PlottingresolutionEditField = uieditfield(app.SettingsforplottingPanel, 'numeric');
            app.PlottingresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingresolutionEditFieldValueChanged, true);
            app.PlottingresolutionEditField.Value = 32;
            app.ShowcontrolpolygonCheckBox = uicheckbox(app.SettingsforplottingPanel);
            app.ShowcontrolpolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontrolpolygonCheckBoxValueChanged, true);
            app.ShowcontrolpolygonCheckBox.Text = 'Show control polygon';
            app.Surface2volumesettingsPanel = uipanel(app.LeftPanel);
            app.Surface2volumesettingsPanel.Title = 'Surface2volume settings';
            app.RunautomationButton = uibutton(app.Surface2volumesettingsPanel, 'push');
            app.RunautomationButton.ButtonPushedFcn = createCallbackFcn(app, @RunautomationButtonPushed, true);
            app.RunautomationButton.Text = 'Run automation';
            app.AnglethresholdEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.AnglethresholdEditFieldLabel.Text = 'Angle threshold';
            app.AnglethresholdEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.AnglethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AnglethresholdEditFieldValueChanged, true);
            app.AnglethresholdEditField.Value = 130;
            app.ThicknessEditFieldLabel = uilabel(app.Surface2volumesettingsPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.Surface2volumesettingsPanel, 'numeric');
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 0.4;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'parula')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surface2volume

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end